classdef ImageColorSegmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUI                        matlab.ui.Figure
        ModifiedImage              matlab.ui.control.Image
        LoadedImage                matlab.ui.control.Image
        LoadButton                 matlab.ui.control.Button
        SegmentationButton         matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        GrubokrawdziDropDownLabel  matlab.ui.control.Label
        BorderDropDown             matlab.ui.control.DropDown
        PrgSliderLabel             matlab.ui.control.Label
        TresholdSlider             matlab.ui.control.Slider
        AddBorderButton            matlab.ui.control.Button
        KrawdzieCheckBox           matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        imagepath; % Full path to image
        segImg; % Segmented image
        brdImg; % Segmented image with borders
        savImg; % Recent image to be saved
        loadImg; % Loaded image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide unused elements at this stage
            app.LoadedImage.Visible = 'off';
            app.ModifiedImage.Visible = 'off';
            app.SaveButton.Enable = 'off';
            app.SegmentationButton.Enable = 'off';
            app.AddBorderButton.Enable = 'off';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Load image and display it
            [file,path] = uigetfile({'*.jpg';'*.jpeg';'*.png'},'Wybierz plik');
            if isequal(file,0) == false
                app.imagepath = fullfile(path,file);
                app.LoadedImage.ImageSource = app.imagepath;
                app.loadImg = imread(app.imagepath);
                app.SegmentationButton.Enable = 'on';
                app.AddBorderButton.Enable = 'off';
                app.LoadedImage.Visible = 'on';
            end
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            % Trigger function that segments image
            threshold = app.TresholdSlider.Value;
            borders = app.KrawdzieCheckBox.Value;
            app.segImg = segmentation(app.imagepath, threshold, borders);
            app.savImg = app.segImg;
            app.ModifiedImage.ImageSource = app.savImg;
            app.ModifiedImage.Visible = 'on';
            app.SaveButton.Enable = 'on';
            app.AddBorderButton.Enable = 'on';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save image in chosen location
            filter = {'*.jpg';'*.jpeg';'*.png'};
            [file, path] = uiputfile(filter,'Wybierz katalog');
            if isequal(file,0) == false
                imwrite(app.savImg,fullfile(path,file));
            end
        end

        % Image clicked function: LoadedImage
        function LoadedImageClicked(app, event)
            imshow(app.loadImg);
        end

        % Image clicked function: ModifiedImage
        function ModifiedImageClicked(app, event)
            imshow(app.savImg);
        end

        % Button pushed function: AddBorderButton
        function AddBorderButtonPushed(app, event)
            thickness = str2num(app.BorderDropDown.Value);
            app.brdImg = borders(app.segImg, thickness);
            app.savImg = app.brdImg;
            app.ModifiedImage.ImageSource = app.savImg;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUI = uifigure('Visible', 'off');
            app.GUI.Name = 'ImageColorSegmentation';
            app.ModifiedImage = uiimage(app.GUI);
            app.ModifiedImage.ImageClickedFcn = createCallbackFcn(app, @ModifiedImageClicked, true);
            app.LoadedImage = uiimage(app.GUI);
            app.LoadedImage.ImageClickedFcn = createCallbackFcn(app, @LoadedImageClicked, true);
            app.LoadButton = uibutton(app.GUI, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Załaduj obraz';
            app.SegmentationButton = uibutton(app.GUI, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentacja barwna';
            app.SaveButton = uibutton(app.GUI, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Zapisz obraz';
            app.GrubokrawdziDropDownLabel = uilabel(app.GUI);
            app.GrubokrawdziDropDownLabel.Text = 'Grubość krawędzi';
            app.BorderDropDown = uidropdown(app.GUI);
            app.BorderDropDown.Items = {'0', '1', '2', '3', '4', '5'};
            app.BorderDropDown.Value = '2';
            app.PrgSliderLabel = uilabel(app.GUI);
            app.PrgSliderLabel.Text = 'Próg';
            app.TresholdSlider = uislider(app.GUI);
            app.TresholdSlider.Limits = [2 5];
            app.TresholdSlider.MajorTicks = [2 3 4 5];
            app.TresholdSlider.MajorTickLabels = {'2', '3', '4', '5'};
            app.TresholdSlider.Value = 3;
            app.AddBorderButton = uibutton(app.GUI, 'push');
            app.AddBorderButton.ButtonPushedFcn = createCallbackFcn(app, @AddBorderButtonPushed, true);
            app.AddBorderButton.Text = 'Dodaj krawędzie';
            app.KrawdzieCheckBox = uicheckbox(app.GUI);
            app.KrawdzieCheckBox.Text = 'Krawędzie';
            % Show the figure after all components are created
            app.GUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageColorSegmentation

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.GUI)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.GUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUI)
        end
    end
end